classdef data_acquisition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataacquisitionUIFigure        matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        MethodPanel                    matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        GridLayout15                   matlab.ui.container.GridLayout
        minLabel                       matlab.ui.control.Label
        timesLabel                     matlab.ui.control.Label
        PeriodEditField                matlab.ui.control.NumericEditField
        PeriodEditFieldLabel           matlab.ui.control.Label
        CycleEditField                 matlab.ui.control.NumericEditField
        CycleEditFieldLabel            matlab.ui.control.Label
        RunScheduleButton              matlab.ui.control.Button
        RepeatCheckBox                 matlab.ui.control.CheckBox
        RunInstanceButton              matlab.ui.control.Button
        TechniqueDropDown              matlab.ui.control.DropDown
        SaveMethodButton               matlab.ui.control.Button
        LoadMethodButton               matlab.ui.control.Button
        GridLayout13                   matlab.ui.container.GridLayout
        GridLayout14                   matlab.ui.container.GridLayout
        AxisequalButton                matlab.ui.control.StateButton
        SaveButton                     matlab.ui.control.Button
        ImportButton                   matlab.ui.control.Button
        Measurement2DropDown           matlab.ui.control.DropDown
        Measurement2DropDownLabel      matlab.ui.control.Label
        Measurement1DropDown           matlab.ui.control.DropDown
        Measurement1DropDownLabel      matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        GridLayout5                    matlab.ui.container.GridLayout
        LogPanel                       matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        LogBox                         matlab.ui.control.ListBox
        MeasuretechniquesettingsPanel  matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        EISSettingsPanel               matlab.ui.container.Panel
        GridLayout12                   matlab.ui.container.GridLayout
        HzLabel_2                      matlab.ui.control.Label
        EISMaxFrequencyEditField       matlab.ui.control.NumericEditField
        MaxfrequencyEditFieldLabel     matlab.ui.control.Label
        HzLabel                        matlab.ui.control.Label
        EISMinFrequencyEditField       matlab.ui.control.NumericEditField
        MinfrequencyEditFieldLabel     matlab.ui.control.Label
        EISnFrequenciesEditField       matlab.ui.control.NumericEditField
        nfrequenciesEditFieldLabel     matlab.ui.control.Label
        FrequencyscanLabel             matlab.ui.control.Label
        VLabel_4                       matlab.ui.control.Label
        EISEACEditField                matlab.ui.control.NumericEditField
        EACEditFieldLabel              matlab.ui.control.Label
        VLabel_3                       matlab.ui.control.Label
        EISEDCEditField                matlab.ui.control.NumericEditField
        EDCEditFieldLabel              matlab.ui.control.Label
        FixedpotentialLabel            matlab.ui.control.Label
        LSVSettingsPanel               matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        VLabel_7                       matlab.ui.control.Label
        LSVEstepEditField              matlab.ui.control.NumericEditField
        EstepEditFieldLabel            matlab.ui.control.Label
        VsLabel                        matlab.ui.control.Label
        LSVScanRateEditField           matlab.ui.control.NumericEditField
        ScanrateEditFieldLabel         matlab.ui.control.Label
        VLabel_6                       matlab.ui.control.Label
        LSVEendEditField               matlab.ui.control.NumericEditField
        EendEditFieldLabel             matlab.ui.control.Label
        VLabel_5                       matlab.ui.control.Label
        LSVEbeginEditField             matlab.ui.control.NumericEditField
        EbeginEditFieldLabel           matlab.ui.control.Label
        CASettingsPanel                matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        sLabel_5                       matlab.ui.control.Label
        CAtRunEditField                matlab.ui.control.NumericEditField
        trunEditFieldLabel             matlab.ui.control.Label
        sLabel_4                       matlab.ui.control.Label
        CAtIntervalEditField           matlab.ui.control.NumericEditField
        tintervalEditFieldLabel        matlab.ui.control.Label
        VLabel_8                       matlab.ui.control.Label
        CAEDCEditField                 matlab.ui.control.NumericEditField
        EDCEditFieldLabel_2            matlab.ui.control.Label
        CVSettingsPanel                matlab.ui.container.Panel
        GridLayout11                   matlab.ui.container.GridLayout
        CVNumberOfScansEditField       matlab.ui.control.NumericEditField
        NumberofscansEditFieldLabel    matlab.ui.control.Label
        VsLabel_2                      matlab.ui.control.Label
        CVScanRateEditField            matlab.ui.control.NumericEditField
        ScanrateEditField_2Label       matlab.ui.control.Label
        VLabel_12                      matlab.ui.control.Label
        CVEstepEditField               matlab.ui.control.NumericEditField
        EstepEditField_2Label          matlab.ui.control.Label
        VLabel_11                      matlab.ui.control.Label
        CVEvertex2EditField            matlab.ui.control.NumericEditField
        Evertex2EditFieldLabel         matlab.ui.control.Label
        VLabel_10                      matlab.ui.control.Label
        CVEvertex1EditField            matlab.ui.control.NumericEditField
        Evertex1EditFieldLabel         matlab.ui.control.Label
        VLabel_9                       matlab.ui.control.Label
        CVEbeginEditField              matlab.ui.control.NumericEditField
        EbeginEditField_2Label         matlab.ui.control.Label
        OtherSettingsPanel             matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        Label                          matlab.ui.control.Label
        PretreatmentsettingsPanel      matlab.ui.container.Panel
        GridLayout7_2                  matlab.ui.container.GridLayout
        sLabel_3                       matlab.ui.control.Label
        tEquilibrationEditFieldLabel   matlab.ui.control.Label
        tEquilibrationEditField        matlab.ui.control.NumericEditField
        sLabel_2                       matlab.ui.control.Label
        tDepositionEditField           matlab.ui.control.NumericEditField
        tDepositionEditFieldLabel      matlab.ui.control.Label
        VLabel_2                       matlab.ui.control.Label
        EdepositionEditField           matlab.ui.control.NumericEditField
        EdepositionEditFieldLabel      matlab.ui.control.Label
        sLabel                         matlab.ui.control.Label
        tConditionEditField            matlab.ui.control.NumericEditField
        tConditionEditFieldLabel       matlab.ui.control.Label
        VLabel                         matlab.ui.control.Label
        EconditionEditField            matlab.ui.control.NumericEditField
        EconditionEditFieldLabel       matlab.ui.control.Label
        CurrentrangePanel              matlab.ui.container.Panel
        GridLayout4_2                  matlab.ui.container.GridLayout
        StartDropDown                  matlab.ui.control.DropDown
        StartDropDownLabel             matlab.ui.control.Label
        MaxDropDown                    matlab.ui.control.DropDown
        MaxDropDownLabel               matlab.ui.control.Label
        MinDropDown                    matlab.ui.control.DropDown
        MinDropDownLabel               matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        ConnecttodevicesPanel          matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        RefreshButton                  matlab.ui.control.Button
        ConnectButton                  matlab.ui.control.Button
        DevicesDropDown                matlab.ui.control.DropDown
    end


    properties (Access = private)
        % Saved settings.
        curSettings;
        % Measurement.
        Measurement;
        % A timer object.
        aTimer;
    end

    methods (Access = private)
        function log(app, message)
            message = string(datetime) + " " + message;
            if(isempty(app.LogBox.Items))
                app.LogBox.Items = cellstr(message);
            else
                app.LogBox.Items(end+1) = cellstr(message);
            end
            app.LogBox.Value = app.LogBox.Items(end);
            scroll(app.LogBox, "bottom");
        end

        function loadSDK(app)
            log(app, "Checking settings.mat");
            fType = exist("settings.mat", "file");
            if(fType == 2)
                log(app, "Loading settings.mat");
                load("settings.mat", "Settings");
                % Check whether settings contains the required field.
                if(isfield(Settings.Default, "dllPath") == 1)
                    % Check whether the previously specified folder
                    % contains the PalmSens Core dll.
                    dllFolder = Settings.Default.dllFolder;
                    dllPath = Settings.Default.dllPath;
                    fType = exist(dllPath, "file");
                    if(fType ~= 2)
                        fType = 0;
                    end
                else
                    fType = 0;
                end
            end
            % Prompt location of SDK library
            drawnow;
            while(fType == 0)
                log(app, "Please specify the location of "...
                    + "PalmSens.Core.Matlab.dll");
                [dllName, dllFolder] =...
                    uigetfile("PalmSens.Core.Matlab.dll",...
                    "Please specify the location of "...
                    + "PalmSens.Core.Matlab.dll");
                % Check whether a file was selected.
                if(isempty(dllFolder))
                    log(app, "Error: no file selected.");
                    continue;
                end
                % Double check to determine if the selected file exists.
                dllPath = fullfile(dllFolder, dllName);
                fType = exist(dllPath, "file");
                if(fType ~= 2)
                    fType = 0;
                end
                % Check to determine whether the correct
                % file was specified.
                if(dllName ~= "PalmSens.Core.Matlab.dll")
                    log(app, "Please select the PalmSens SDK "...
                        + "library named PalmSens.Core.Matlab.dll");
                    continue;
                end
            end
            % Store path of SDK library in settings.
            Settings.Default.dllFolder = dllFolder;
            Settings.Default.dllPath = dllPath;
            if(exist("settings.mat", "file") ~= 2)
                save("settings.mat", "Settings");
            else
                save("settings.mat", "Settings", "-append");
            end
            % Add PalmSens SDK library to path.
            % addpath(genpath(dllFolder));
            % log(app, "PalmSens SDK added to path.");
            % Load PalmSens SDK library.
            Settings.PSSDK = NET.addAssembly(dllPath);
            log(app, "PalmSens SDK loaded succesfully.");
            % Update settings.
            app.curSettings = Settings;
            log(app, "PalmSens SDK location saved.");
            drawnow;
        end

        function scanDevices(app)
            % Get connected devices.
            deviceList = GetConnectedDevices();
            nDevices = deviceList.Count;
            log(app, num2str(nDevices) +...
                " device(s) discovered:");
            devices = cell(nDevices);
            % Display connected devices.
            for ii = 0:nDevices-1
                log(app, num2str(ii+1) + " - " +...
                    char(deviceList.Item(ii).ToString()));
                devices{ii+1} = char(deviceList.Item(ii).ToString());
            end
            % Disable/enable connect button depending
            % on the presence of devices.
            if(nDevices == 0)
                app.ConnectButton.Enable = "off";
                devices = cellstr("No Devices Detected");
            else
                app.ConnectButton.Enable = "on";
            end
            % Update popup menu and store device information.
            app.DevicesDropDown.Items = devices;
            app.DevicesDropDown.ItemsData = 1:nDevices;
            app.curSettings.Devices.deviceList = deviceList;
            app.curSettings.Devices.nDevices = nDevices;
            drawnow;
        end

        function createMethod(app, technique)
            % Create a new method of the specified type.
            method = NewMethod(technique);
            updateMethodFields(app, method, technique);
        end

        function updateMethodFields(app, method, technique)
            % Update the gui fields with the methods settings.
            % Set the current range.
            setCurrentRange(app, method);
            % Set the pretreatment settings.
            setPreTreatmentSettings(app, method);
            % This example only includes method specific fields
            % for LSV, CV and EIS. Other panels can be added
            % using GUIDE and the respective functions to
            % update their fields can be added here.
            if(technique == 1)
                updateLSVMethodFields(app, method);
            elseif(technique == 2)
                updateCVMethodFields(app, method);
            elseif(technique == 7)
                updateCAMethodFields(app, method);
            elseif(technique == 16)
                updateEISMethodFields(app, method);
            else
                updateOtherMethodFields(app, method);
            end
            % Store the method file in the figure.
            app.curSettings.Method.method = method;
        end

        function setCurrentRange(app, method)
            % Check if current range is fixed or auto.
            fixedCR = isa(method.Ranging, "PalmSens.AutoRanging")...
                == false;
            if(fixedCR)
                index = 8 - method.Ranging.MaximumCurrentRange.CRbyte;
                if(index > 8)
                    index = 8;
                elseif (index < 1)
                    index = 1;
                end
                app.MaxDropDown.Value = index;
                app.MinDropDown.Value = index;
                app.StartDropDown.Value = index;
            else
                min = 8 - method.Ranging.MinimumCurrentRange.CRbyte;
                if(min > 8)
                    min = 8;
                elseif (min < 1)
                    min = 1;
                end
                max = 8 - method.Ranging.MaximumCurrentRange.CRbyte;
                if(max > 8)
                    max = 8;
                elseif (max < 1)
                    max = 1;
                end
                start = 8 - method.Ranging.StartCurrentRange.CRbyte;
                if(start > 8)
                    start = 8;
                elseif (start < 1)
                    start = 1;
                end
                app.MaxDropDown.Value = max;
                app.MinDropDown.Value = min;
                app.StartDropDown.Value = start;
            end
        end

        function setPreTreatmentSettings(app, method)
            app.EconditionEditField.Value =...
                double(method.ConditioningPotential);
            app.tConditionEditField.Value =...
                double(method.ConditioningTime);
            app.EdepositionEditField.Value =...
                double(method.DepositionPotential);
            app.tDepositionEditField.Value =...
                double(method.DepositionTime);
            app.tEquilibrationEditField.Value =...
                double(method.EquilibrationTime);
        end

        function updateLSVMethodFields(app, method)
            % Hide the other techniques settings panels
            % and show the LSV settings panel.
            app.CASettingsPanel.Visible = "off";
            app.CVSettingsPanel.Visible = "off";
            app.EISSettingsPanel.Visible = "off";
            app.OtherSettingsPanel.Visible = "off";
            app.LSVSettingsPanel.Visible = "on";
            % Set the LSV specific settings.
            app.LSVEbeginEditField.Value =...
                double(method.BeginPotential);
            app.LSVEendEditField.Value =...
                double(method.EndPotential);
            app.LSVEstepEditField.Value =...
                double(method.StepPotential);
            app.LSVScanRateEditField.Value =...
                double(method.Scanrate);
        end

        function updateCAMethodFields(app, method)
            % Hide the other techniques settings panels
            % and show the CA settings panel.
            app.CASettingsPanel.Visible = "on";
            app.CVSettingsPanel.Visible = "off";
            app.EISSettingsPanel.Visible = "off";
            app.OtherSettingsPanel.Visible = "off";
            app.LSVSettingsPanel.Visible = "off";
            %Set the CA specific settings
            app.CAEDCEditField.Value =...
                double(method.BeginPotential);
            app.CAtIntervalEditField.Value =...
                double(method.IntervalTime);
            app.CAtRunEditField.Value =...
                double(method.RunTime);
        end

        function updateCVMethodFields(app, method)
            % Hide the other techniques settings panels
            % and show the CV settings panel.
            app.CASettingsPanel.Visible = "off";
            app.CVSettingsPanel.Visible = "on";
            app.EISSettingsPanel.Visible = "off";
            app.OtherSettingsPanel.Visible = "off";
            app.LSVSettingsPanel.Visible = "off";
            % Set the CV specific settings.
            app.CVEbeginEditField.Value =...
                double(method.BeginPotential);
            app.CVEvertex1EditField.Value =...
                double(method.Vtx1Potential);
            app.CVEvertex2EditField.Value =...
                double(method.Vtx2Potential);
            app.CVEstepEditField.Value =...
                double(method.StepPotential);
            app.CVScanRateEditField.Value =...
                double(method.Scanrate);
            app.CVNumberOfScansEditField.Value =...
                double(method.nScans);
        end

        function updateEISMethodFields(app, method)
            % Hide the other techniques settings panels
            % and show the EIS settings panel.
            app.CASettingsPanel.Visible = "off";
            app.CVSettingsPanel.Visible = "off";
            app.EISSettingsPanel.Visible = "on";
            app.OtherSettingsPanel.Visible = "off";
            app.LSVSettingsPanel.Visible = "off";
            % Set the EIS specific settings.
            app.EISEDCEditField.Value =...
                double(method.Potential);
            app.EISEACEditField.Value =...
                double(method.Eac);
            app.EISnFrequenciesEditField.Value =...
                double(method.nFrequencies);
            app.EISMinFrequencyEditField.Value =...
                double(method.MinFrequency);
            app.EISMaxFrequencyEditField.Value =...
                double(method.MaxFrequency);
            % Set the methods scantype to fixed
            % potential en frequency scan.
            method.ScanType =...
                "FixedPotential";
            method.FreqType =...
                PalmSens.Techniques.enumFrequencyType.Fixed;
            method.FixedFrequency = 40000;
            method.RunTime = 0;
        end

        function updateOtherMethodFields(app, ~)
            % Hide the other techniques settings panels
            % and show the other settings panel.
            app.CASettingsPanel.Visible = "off";
            app.CVSettingsPanel.Visible = "off";
            app.EISSettingsPanel.Visible = "off";
            app.OtherSettingsPanel.Visible = "on";
            app.LSVSettingsPanel.Visible = "off";
        end

        function beginListener(app, ~, eventArgs, ~)
            measurement = eventArgs;
            % Store curve in figure handle.
            app.Measurement.NET.measurements(end).measurement =...
                measurement;
            app.Measurement.measurement.StartDataListener(...
                @app, @app.dataListener)
            log(app, "Measurement started...");
        end

        function endListener(app, ~, ~, ~)
            if(app.Measurement.abort == false)
                if(app.RunInstanceButton.Enable)
                    log(app, "Measurement completed.");
                    app.RunInstanceButton.Text = 'Run Instance';
                    app.RunInstanceButton.FontColor = "black";
                    app.RunInstanceButton.BackgroundColor = [0.96, 0.96, 0.96];
                    app.RunScheduleButton.Enable = "on";
                    app.Measurement.measurement.StopDataListener();
                    % Process data when measurement is completed.
                    m = processMeasurement(app,...
                        app.Measurement.NET.measurements(end));
                    plotData(app, m);
                else
                    log(app, "Measurement cycle " + app.aTimer.TasksExecuted ...
                        + " completed.");
                    app.Measurement.measurement.StopDataListener();
                    m = processMeasurement(app,...
                        app.Measurement.NET.measurements(end));
                    plotData(app, m);
                    if(app.aTimer.TasksExecuted >= app.CycleEditField.Value)
                        app.RunScheduleButton.Text = 'Run Schedule';
                        app.RunScheduleButton.FontColor = "black";
                        app.RunScheduleButton.BackgroundColor = [0.96, 0.96, 0.96];
                        app.RunInstanceButton.Enable = "on";
                    end
                end
            else
                log(app, "Measurement aborted.");
            end
        end

        function curveListener(app, ~, eventArgs, ~)
            if(app.Measurement.abort == false)
                curve = eventArgs.GetCurve();
                % Store curve in figure handle.
                app.Measurement.NET.measurements(end).curves.Add(curve);
                log(app, "Curve Received.");
            end
        end

        function dataListener(app, ~, eventArgs, ~)
            log(app, "data packet(s) " + ...
                num2str(eventArgs.StartIndex + 1) +" to "...
                + num2str(eventArgs.Count + eventArgs.StartIndex)...
                + " received.");
            if(strcmp(char(...
                    app.Measurement.NET.measurements(end).measurement.Method.Name),...
                    "Impedance Spectroscopy"))
                plotEISDataRealtime(app)
            else
                plotDataRealtime(app)
            end
        end

        function idleListener(app, ~, eventArgs, ~)
            status = eventArgs.GetStatus();
            current = status.CurrentReading.ValueInRange;
            unitCurrent = char(status.CurrentReading.CurrentRange.ToString());
            potential = status.PotentialReading.Value;
            log(app, "Idle Status: Potential (" + ...
                num2str(potential) + " V) Current (" +...
                num2str(current) + " * " + unitCurrent + ").");
        end

        function measurement = processMeasurement(app, rawData)
            % Convert .NET measurement to matlab structs.
            % Impedance Spectroscopy data is handled differently.
            if(strcmp(char(rawData.measurement.Method.Name),...
                    "Impedance Spectroscopy"))
                measurement = processEISMeasurement(app, rawData);
            else
                measurement = struct("name", {}, "type", {},...
                    "date", {}, "curves", {}, "measurement", {});
                measurement(1).name =...
                    [num2str(length(app.Measurement.measurements) + 1)...
                    , '.', char(rawData.measurement.Method.ShortName)];
                measurement(1).date = datestr(now);
                measurement(1).type = char(rawData.measurement.Method.Name);
                measurement(1).curves = struct("xUnit", {}, "xData", {},...
                    "yUnit", {}, "yData", {});
                % Loop added for CV, in the case of a CV with multiple
                % scans each scan is stored as a seperate curve.
                for i = 1:rawData.curves.Count
                    measurement(1).curves(i).xUnit =...
                        char(rawData.curves.Item(i - 1).XUnit.ToString());
                    measurement(1).curves(i).xData =...
                        double(rawData.curves.Item(i - 1).GetXValues());
                    measurement(1).curves(i).yUnit =...
                        char(rawData.curves.Item(i - 1).YUnit.ToString());
                    measurement(1).curves(i).yData =...
                        double(rawData.curves.Item(i - 1).GetYValues());
                end
            end
            app.Measurement.measurements(end+1) = measurement;
            % Add curves to measurement overview.
            addToOverview(app, measurement);
        end

        function measurement = processEISMeasurement(~, rawData)
            measurement = struct('name', {}, 'type', {},...
                'date', {}, 'curves', {});
            measurement(1).name = num2str(length(app.Measurement.measurements) + 1)...
                + "." + char(rawData.measurement.Method.ShortName);
            measurement(1).date = datestr(now);
            measurement(1).type = char(rawData.measurement.Method.Name);
            % Get impedance data arrays.
            dataArrays = rawData.measurement.DataSet.GetDataArrays();
            ZRe = dataArrays(5);
            ZIm = dataArrays(6);
            Zabs = dataArrays(7);
            Phase = dataArrays(8);
            Frequency = dataArrays(4);
            % Nyquist curve.
            measurement(1).curves(1).xUnit = "ZRe(" + char(ZRe.Unit.ToString()) + ")";
            measurement(1).curves(1).xData = double(ZRe.GetValues());
            measurement(1).curves(1).yUnit = "ZIm(" + char(ZIm.Unit.ToString()) + ")";
            measurement(1).curves(1).yData = double(ZIm.GetValues());
            % Bode curves.
            % Impedance over Frequency.
            measurement(1).curves(2).xUnit = ['Frequency(' char(Frequency.Unit.ToString()) ')'];
            measurement(1).curves(2).xData = double(Frequency.GetValues());
            measurement(1).curves(2).yUnit = ['Z(' char(Zabs.Unit.ToString()) ')'];
            measurement(1).curves(2).yData = double(Zabs.GetValues());
            % Phase over Frequency.
            measurement(1).curves(3).xUnit = ['Frequency(' char(Frequency.Unit.ToString()) ')'];
            measurement(1).curves(3).xData = double(Frequency.GetValues());
            measurement(1).curves(3).yUnit = ['-Phase(' char(Phase.Unit.ToString()) ')'];
            measurement(1).curves(3).yData = -1 .* double(Phase.GetValues());
        end

        function addToOverview(app, measurement)
            % Check whether the menu contains a char array or a cell of
            % strings, if necessary create the cell for the list of
            % measurements.
            if(iscell(app.Measurement1DropDown.Items) == 0)
                list = cell(1);
                list{1} = 'none';
            else
                list = app.Measurement1DropDown.Items;
            end
            % Update the list of measurements.
            list{end + 1} = measurement.name;
            app.Measurement1DropDown.Items = list;
            app.Measurement1DropDown.ItemsData = 1:length(list);
            app.Measurement1DropDown.Value = length(list);
            app.Measurement2DropDown.Items = list;
            app.Measurement2DropDown.ItemsData = 1:length(list);
            app.SaveButton.Enable = "on";
            app.Measurement2DropDown.Value = 1;
        end

        function technique = getMethodTechnique(~, method)
            if (strcmp(char(method.Name),'Linear Sweep Voltammetry'))
                technique = 1;
                return;
            elseif (strcmp(char(method.Name),'Cyclic Voltammetry'))
                technique = 2;
                return;
            elseif (strcmp(char(method.Name),'AC Voltammetry'))
                technique = 3;
                return;
            elseif (strcmp(char(method.Name),'Differential Pulse Voltammetry'))
                technique = 4;
                return;
            elseif (strcmp(char(method.Name),'Square Wave Voltammetry'))
                technique = 5;
                return;
            elseif (strcmp(char(method.Name),'Normal Pulse Voltammetry'))
                technique = 6;
                return;
            elseif (strcmp(char(method.Name),'Chronoamperometry'))
                technique = 7;
                return;
            elseif (strcmp(char(method.Name),'MultiStep Amperometry'))
                technique = 8;
                return;
            elseif (strcmp(char(method.Name),'Fast Amperometry'))
                technique = 9;
                return;
            elseif (strcmp(char(method.Name),'Pulsed Amperometric Detection'))
                technique = 10;
                return;
            elseif (strcmp(char(method.Name),'Multiple Pulse Amperometry'))
                technique = 11;
                return;
            elseif (strcmp(char(method.Name),'Open Circuit Potentiometry'))
                technique = 12;
                return;
            elseif (strcmp(char(method.Name),'Chronopotentiometry'))
                technique = 13;
                return;
            elseif (strcmp(char(method.Name),'MultiStep Potentiometry'))
                technique = 14;
                return;
            elseif (strcmp(char(method.Name),'Chronopotentiometric Stripping'))
                technique = 15;
                return;
            elseif (strcmp(char(method.Name),'Impedance Spectroscopy'))
                technique = 16;
                return;
            elseif (strcmp(char(method.Name),'Fast Cyclic Voltammetry'))
                technique = 17;
                return;
            end
        end

        function [valid, method] = updateMethod(app)
            valid = true;
            method = app.curSettings.Method.method;
            technique = getMethodTechnique(app, method);
            % Attempt to update the methods current range
            % with the specified values.
            method = updateMethodCurrentRange(app, method);
            if(method == false)
                valid = false;
                return;
            end
            % Attempt to update the methods pretreatment
            % settings with the specified values.
            method = updateMethodPretreatment(app, method);
            if(method == false)
                valid = false;
                return;
            end
            if(technique == 1)
                method = updateMethodLSV(app, method);
                if(method == false)
                    valid = false;
                    return;
                end
            elseif (technique == 2)
                method = updateMethodCV(app, method);
                if(method == false)
                    valid = false;
                    return;
                end
            elseif (technique == 7)
                method = updateMethodCA(app, method);
                if(method == false)
                    valid = false;
                    return;
                end
            elseif (technique == 16)
                method = updateMethodEIS(app, method);
                if(method == false)
                    valid = false;
                    return;
                end
            end
        end

        function method = updateMethodCurrentRange(app, method)
            if (app.MinDropDown.Value == app.MaxDropDown.Value)
                uA = 10^(5 - app.MaxDropDown.Value);
                % Convert MicroAmps into current range objects.
                fixedCurrentRange = PalmSens.CurrentRange.FromMicroamps(uA);
                % Create a new fixed current range object.
                newRangingObj = PalmSens.FixedCurrentRange();
                % Set the fixed current range of the new object.
                newRangingObj.MaximumCurrentRange = fixedCurrentRange;
                %Setting the fixed current range object in the method;
                method.Ranging = newRangingObj;
            else
                uAMin = 10^(5 - app.MinDropDown.Value);
                uAMax = 10^(5 - app.MaxDropDown.Value);
                uAStart = 10^(5 - app.StartDropDown.Value);
                minCurrentRange = PalmSens.CurrentRange.FromMicroamps(uAMin);
                maxCurrentRange = PalmSens.CurrentRange.FromMicroamps(uAMax);
                startCurrentRange = PalmSens.CurrentRange.FromMicroamps(uAStart);
                % Create a new Current AutoRanging object.
                newRangingObj = PalmSens.AutoRanging(...
                    minCurrentRange, maxCurrentRange, startCurrentRange);
                % Setting the fixed current range object in the method.
                method.Ranging = newRangingObj;
            end
        end

        function method = updateMethodPretreatment(app, method)
            % Attempt to assign the specified pretreatment
            % settings to the method.
            fail = false;
            try
                method.ConditioningPotential =...
                    app.EconditionEditField.Value;
                fail = fail || isnan(method.ConditioningPotential);
                method.ConditioningTime =...
                    app.tConditionEditField.Value;
                fail = fail || isnan(method.ConditioningTime);
                method.DepositionPotential =...
                    app.EdepositionEditField.Value;
                fail = fail || isnan(method.DepositionPotential);
                method.DepositionTime =...
                    app.tDepositionEditField.Value;
                fail = fail || isnan(method.DepositionTime);
            catch
                % Return false if error occured when setting
                % the methods pretreatment settings.
                method = false;
            end
            if(fail)
                method = false;
            end
        end

        function method = updateMethodLSV(app, method)
            fail = false;
            try
                method.EquilibrationTime =...
                    app.tEquilibrationEditField.Value;
                fail = fail || isnan(method.EquilibrationTime);
                method.BeginPotential =...
                    app.LSVEbeginEditField.Value;
                fail = fail || isnan(method.BeginPotential);
                method.EndPotential = app.LSVEendEditField.Value;
                fail = fail || isnan(method.EndPotential);
                method.StepPotential = app.LSVEstepEditField.Value;
                fail = fail || isnan(method.StepPotential);
                method.Scanrate = app.LSVScanRateEditField.Value;
                fail = fail || isnan(method.Scanrate);
            catch
                method = false;
            end
            if(fail)
                method = false;
            end
        end

        function method = updateMethodCV(app, method)
            fail = false;
            try
                method.EquilibrationTime =...
                    app.tEquilibrationEditField.Value;
                fail = fail || isnan(method.EquilibrationTime);
                method.BeginPotential = app.CVEbeginEditField.Value;
                fail = fail || isnan(method.BeginPotential);
                method.Vtx1Potential = app.CVEvertex1EditField.Value;
                fail = fail || isnan(method.Vtx1Potential);
                method.Vtx2Potential = app.CVEvertex2EditField.Value;
                fail = fail || isnan(method.Vtx2Potential);
                method.StepPotential = app.CVEstepEditField.Value;
                fail = fail || isnan(method.StepPotential);
                method.Scanrate = app.CVScanRateEditField.Value;
                fail = fail || isnan(method.Scanrate);
                method.nScans = app.CVNumberOfScansEditField.Value;
                fail = fail || isnan(method.nScans);
            catch
                method = false;
            end
            if(fail)
                method = false;
            end
        end

        function method = updateMethodCA(app, method)
            fail = false;
            try
                method.EquilibrationTime =...
                    app.tEquilibrationEditField.Value;
                fail = fail || isnan(method.EquilibrationTime);
                method.BeginPotential = app.CAEDCEditField.Value;
                fail = fail || isnan(method.BeginPotential);
                method.IntervalTime = app.CAtIntervalEditField.Value;
                fail = fail || isnan(method.IntervalTime);
                method.RunTime = app.CAtRunEditField.Value;
                fail = fail || isnan(method.RunTime);
            catch
                method = false;
            end
            if(fail)
                method = false;
            end
        end

        function method = updateMethodEIS(app, method)
            fail = false;
            try
                method.EquilibrationTime =...
                    app.tEquilibrationEditField.Value;
                fail = fail || isnan(method.EquilibrationTime);
                method.Potential = app.EISEDCEditField.Value;
                fail = fail || isnan(method.Potential);
                method.Eac = app.EISEACEditField.Value;
                fail = fail || isnan(method.Eac);
                method.nFrequencies = app.EISnFrequenciesEditField.Value;
                fail = fail || isnan(method.nFrequencies);
                method.MinFrequency = app.EISMinFrequencyEditField.Value;
                fail = fail || isnan(method.MinFrequency);
                method.MaxFrequency = app.EISMaxFrequencyEditField.Value;
                fail = fail || isnan(method.MaxFrequency);
            catch
                method = false;
            end
            if(fail)
                method = false;
            end
        end

        function plotData(app, measurement)
            curve = measurement.curves(1);
            app.curSettings.Plot.measurement = measurement;
            app.curSettings.Plot.axesEqual = app.AxisequalButton.Value;
            % Set the handles of the axes to plot the data in.
            % axes(app.UIAxes);
            % In the case of a CV plot the curve of each scan.
            if(strcmp(measurement.type, "Cyclic Voltammetry"))
                xData = zeros(length(measurement.curves), length(measurement.curves(1).xData));
                yData = zeros(length(measurement.curves), length(measurement.curves(1).yData));
                scans = cell(1,length(measurement.curves));
                for i = 1:length(measurement.curves)
                    xData(i,:) = measurement.curves(i).xData;
                    yData(i,:) = measurement.curves(i).yData;
                    scans{i} = [measurement.name ' scan ' num2str(i)];
                end
                plot(app.UIAxes, xData', yData');
                title(app.UIAxes, measurement.type);
                xlabel(app.UIAxes, curve.xUnit);
                ylabel(app.UIAxes, curve.yUnit);
                legend(app.UIAxes, scans);
            else
                plot(app.UIAxes, curve.xData, curve.yData);
                title(app.UIAxes, measurement.type);
                xlabel(app.UIAxes, curve.xUnit);
                ylabel(app.UIAxes, curve.yUnit);
                legend(app.UIAxes, measurement.name);
            end
            if(app.curSettings.Plot.axesEqual)
                axis(app.UIAxes, 'equal');
            else
                axis(app.UIAxes, 'auto');
            end
        end

        function plotEISDataRealtime(app)
            measurement = app.Measurement.NET.measurements(end).measurement;
            app.curSettings.Plot.axesEqual = app.AxisequalButton.Value;
            % Get impedance data arrays (See processEISMeasurement function
            % for extracting data for bode plots).
            dataArrays = measurement.DataSet.GetDataArrays();
            ZRe = dataArrays(5);
            ZIm = dataArrays(6);
            % Nyquist curve.
            x = double(ZRe.GetValues());
            y = double(ZIm.GetValues());
            % Set the handles of the axes to plot the data in.
            % axes(handles.plotArea);
            plot(app.UIAxes, x, y);
            title(app.UIAxes, char(measurement.Title));
            xlabel(app.UIAxes, ['ZRe(' char(ZRe.Unit.ToString()) ')']);
            ylabel(app.UIAxes, ['ZIm(' char(ZIm.Unit.ToString()) ')']);
            if(app.curSettings.Plot.axesEqual)
                axis(app.UIAxes, 'equal');
            else
                axis(app.UIAxes, 'auto');
            end
        end

        function plotDataRealtime(app)
            measurement = app.Measurement.NET.measurements(end).measurement;
            app.curSettings.Plot.axesEqual = app.AxisequalButton.Value;
            curves = measurement.GetCurveArray();
            % In the case of a CV plot the curve of each scan.
            if(strcmp(char(measurement.Method.Name), "Cyclic Voltammetry"))
                x = zeros(curves.Length, length(double(curves(1).GetXValues())));
                y = zeros(curves.Length, length(double(curves(1).GetXValues())));
                for i = 1:curves.Length
                    x(i,1:length(double(curves(i).GetXValues()))) = double(curves(i).GetXValues());
                    y(i,1:length(double(curves(i).GetXValues()))) = double(curves(i).GetYValues());
                end
                x=x';
                y=y';
            else
                x = double(curves(1).GetXValues());
                y = double(curves(1).GetYValues());
            end
            % axes(handles.plotArea); %Set the handles of the axes to plot the data in
            plot(app.UIAxes, x, y);
            title(app.UIAxes, char(measurement.Title));
            xlabel(app.UIAxes, char(curves(1).XUnit.ToString()));
            ylabel(app.UIAxes, char(curves(1).YUnit.ToString()));
            if(app.curSettings.Plot.axesEqual)
                axis(app.UIAxes, 'equal');
            else
                axis(app.UIAxes, 'auto');
            end
        end

        function updatePlots(app)
            % no measurements are selected, thus clear the plot.
            if(isempty(app.Measurement1DropDown.Items) || isempty(app.Measurement2DropDown.Items))
                cla(app.UIAxes);
                return;
            end
            if(app.Measurement1DropDown.Value >= 1 && isempty(app.Measurement2DropDown.Items))
                plotData(app, app.Measurement.measurements(app.Measurement1DropDown.Value));
                % Plot a single measurement.
            elseif (isempty(app.Measurement1DropDown.Items) && app.Measurement2DropDown.Value >= 1)
                plotData(app, app.Measurement.measurements(app.Measurement2DropDown.Value));
                % Plot two measurements.
            elseif (app.Measurement1DropDown.Value ~= app.Measurement2DropDown.Value)
                plotDataOverlay(app,...
                    app.Measurement.measurements(app.Measurement1DropDown.Value),...
                    app.Measurement.measurements(app.Measurement2DropDown.Value));
            else
                plotData(app, app.Measurement.measurements(app.Measurement1DropDown.Value));
            end
        end

        function plotDataOverlay(app, measurement1, measurement2)
            curve1 = measurement1.curves(1);
            curve2 = measurement2.curves(1);
            names = cell(1, length(measurement1.curves) + length(measurement2.curves));
            % Set start index for the array of curve names used in the
            % legend.
            ni = 1;
            % In the case of a CV get the curves of all scans.
            if(strcmp(measurement1.type, "Cyclic Voltammetry"))
                x1 = zeros(length(measurement1.curves), length(measurement1.curves(1).xData));
                y1 = zeros(length(measurement1.curves), length(measurement1.curves(1).yData));
                for i = 1:length(measurement1.curves)
                    x1(i,:) = measurement1.curves(i).xData;
                    y1(i,:) = measurement1.curves(i).yData;
                    names{ni} = [measurement1.name ' scan ' num2str(i)];
                    ni = ni + 1;
                end
                x1=x1';
                y1=y1';
            else
                x1 = curve1.xData;
                y1 = curve1.yData;
                names{ni} = measurement1.name;
                ni = ni + 1;
            end
            % In the case of a CV get the curves of all scans.
            if(strcmp(measurement2.type, "Cyclic Voltammetry"))
                x2 = zeros(length(measurement2.curves), length(measurement2.curves(1).xData));
                y2 = zeros(length(measurement2.curves), length(measurement2.curves(1).yData));
                for i = 1:length(measurement2.curves)
                    x2(i,:) = measurement2.curves(i).xData;
                    y2(i,:) = measurement2.curves(i).yData;
                    names{ni} = [measurement2.name ' scan ' num2str(i)];
                    ni = ni + 1;
                end
                x2=x2';
                y2=y2';
            else
                x2 = curve2.xData;
                y2 = curve2.yData;
                names{ni} = measurement2.name;
            end
            if(strcmp(curve1.xUnit,curve2.xUnit) == 0 || strcmp(curve1.yUnit,curve2.yUnit) == 0)
                log(app, 'Cannot plot measurements with different units on the axes');
                return;
            end
            app.curSettings.Plot.measurement = measurement1;
            app.curSettings.Plot.axesEqual = app.AxisequalButton.Value;
            % Set the handles of the axes to plot the data in.
            % axes(handles.plotArea);
            plot(app.UIAxes, x1, y1, x2, y2);
            title(app.UIAxes, [measurement1.type ' & ' measurement2.type]);
            xlabel(app.UIAxes, curve1.xUnit);
            ylabel(app.UIAxes, curve1.yUnit);
            legend(app.UIAxes, names(1:2));
            if(app.curSettings.Plot.axesEqual)
                axis(app.UIAxes, 'equal');
            else
                axis(app.UIAxes, 'auto');
            end
        end

        function abortMeasuring(app, varargin)
            app.Measurement.abort = true;
            app.Measurement.measurement.Abort();
            log(app, "Aborting Measurement...");
            if(length(app.Measurement.NET.measurements) == 1)
                app.Measurement.measurements =...
                    struct('name', {}, 'type', {},...
                    'date', {}, 'curves', {});
            else
                app.Measurement.NET.measurements =...
                    app.Measurement.NET.measurements(1:end-1);
            end
        end

        function startNewMeasuring(app, varargin)
            app.Measurement.abort = false;
            [valid, method] = updateMethod(app);
            if(valid == false)
                log(app, "Error! Could not run the method. " + ...
                    "Please make sure the methods settings are correct.");
                return;
            end
            app.Measurement.NET.measurements(end+1).curves =...
                NET.createGeneric('System.Collections.Generic.List',...
                {'PalmSens.Plottables.Curve'}, 10);
            app.Measurement.measurement.New(method);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set timer.
            app.aTimer = timer;
            % Reset LogBox.
            app.LogBox.Items = {};
            % Reset measurement drop down.
            app.Measurement1DropDown.Items = {};
            app.Measurement2DropDown.Items = {};
            % Assign TechniqueDropDown data.
            app.TechniqueDropDown.ItemsData =...
                1:length(app.TechniqueDropDown.Items);
            % Assign range drop down data.
            app.MinDropDown.ItemsData =...
                1:length(app.MinDropDown.Items);
            app.MaxDropDown.ItemsData =...
                1:length(app.MaxDropDown.Items);
            app.StartDropDown.ItemsData =...
                1:length(app.StartDropDown.Items);
            app.Measurement1DropDown.ItemsData =...
                1:length(app.Measurement1DropDown.Items);
            app.Measurement2DropDown.ItemsData =...
                1:length(app.Measurement2DropDown.Items);
            % Create a struct for storing measurements.
            app.Measurement.NET.measurements =...
                struct("curves", {}, "measurement", {});
            app.Measurement.measurements =...
                struct("name", {}, "type", {},...
                "date", {}, "curves", {}, "measurement", {});
            app.Measurement.abort = false;
            % Load SDK.
            loadSDK(app);
            drawnow;
            % Scan devices.
            scanDevices(app);
            % Initcialize methods.
            createMethod(app, app.TechniqueDropDown.Value);
            drawnow;
            % Disable the run instance measurement button by default.
            app.RunInstanceButton.Enable = "off";
            app.SaveButton.Enable = "off";
            % Disable the run schedule measurement button by default.
            app.RunScheduleButton.Enable = "off";
            drawnow;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % Refresh or test Connection.
            if(strcmp(app.RefreshButton.Text, "Refresh"))
                scanDevices(app);
            else
                % Testing connection by listning to
                % idle packages for 5 seconds.
                % Disable test button during testing.
                app.RefreshButton.Enable = "off";
                log(app, "Checking for idle status packets " + ...
                    "(for 5 seconds).");
                % Start recording idle data.
                app.RunInstanceButton.Enable = "off";
                app.RunScheduleButton.Enable = "off";
                app.Measurement.measurement.GetIdleData;
                pause(5);
                % Stop recording idle data.
                app.Measurement.measurement.StopIdleData;
                log(app, "Check completed.");
                % Reenable test button after test performed.
                app.RunInstanceButton.Enable = "on";
                app.RunScheduleButton.Enable = app.RepeatCheckBox.Value;
                app.RefreshButton.Enable = "on";
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if(strcmp(app.ConnectButton.Text, "Connect"))
                if(app.curSettings.Devices.nDevices < 1)
                    errordlg("No devices connected. Please make " + ...
                        "sure your device is connected, " + ...
                        "or remove the usb and plug it back in, " + ...
                        "and click on the refresh button.", ...
                        "Failed to connect.");
                    log(app, "No devices connected. Please make " + ...
                        "sure your device is connected, " + ...
                        "or remove the usb and plug it back in, " + ...
                        "and click on the refresh button.");
                    return;
                end
                % Retreive the list of connected devices.
                deviceList = app.curSettings.Devices.deviceList;
                % Get index of selected device.
                index = app.DevicesDropDown.Value - 1;
                deviceName = char(deviceList.Item(index).ToString());
                log(app, "Attempting to open a connection with " + ...
                    deviceName);
                % Attempt to open a connection with the selected device.
                comm = OpenConnection(deviceList.Item(index));
                % Check whether the connection was succesfully opened.
                if(comm == false)
                    errordlg("Error while opening a connection to " + ...
                        deviceName + ". Please check whether the " + ...
                        "specified device is a PalmSens device " + ...
                        "and try to open the connection again",...
                        "Failed to connect.");
                    log(app, "Error while opening a connection to " + ...
                        deviceName + ". Please check whether the " + ...
                        "specified device is a PalmSens device " + ...
                        "and try to open the connection again");
                    return;
                end
                log(app, "Succesfully connected to " + deviceName);
                drawnow;
                % Instantiate measurement class with event listners
                % used to record data from the device.
                app.Measurement.measurement =...
                    MeasurementGUI(app, comm, @app.beginListener,...
                    @app.endListener, @app.curveListener,...
                    @app.idleListener);
                % Enable the run measurement button.
                app.RunInstanceButton.Enable = "on";
                app.RunScheduleButton.Enable = app.RepeatCheckBox.Value;
                % Change Connect and Refresh buttons to Disconnect and
                % Test.
                app.ConnectButton.Text = "Disconnect";
                app.ConnectButton.FontColor = "white";
                app.ConnectButton.BackgroundColor = "red";
                app.RefreshButton.Text = "Test";
                % Store the devices communication objecthandle
                % in the GUIs handles.
                app.curSettings.Devices.comm = comm;
            else
                app.curSettings.Devices.comm.Disconnect();
                delete(app.curSettings.Devices.comm);
                drawnow;
                log(app, "Device disconnected.");
                % Change Disconnect and Test buttons to Connect and
                % Refresh.
                % Reenable if disconnected during testing.
                app.RefreshButton.Enable = "on";
                app.RefreshButton.Text = "Refresh";
                app.ConnectButton.Text = "Connect";
                app.ConnectButton.FontColor = "black";
                app.ConnectButton.BackgroundColor = [0.96, 0.96, 0.96];
                % Delete measurement class to remove the even listners and
                % prevent samples from being recorded multiple times.
                if(isfield(app, "Measurement"))
                    if(isfield(app.Measurement, "measurement") ~= 0)
                        delete(app.Measurement.measurement);
                    end
                end
                % Disable the run measurement button.
                app.RunInstanceButton.Enable = "off";
                app.RunScheduleButton.Enable = "off";
            end
        end

        % Value changed function: TechniqueDropDown
        function TechniqueDropDownValueChanged(app, event)
            createMethod(app, app.TechniqueDropDown.Value);
        end

        % Button pushed function: LoadMethodButton
        function LoadMethodButtonPushed(app, event)
            Settings = app.curSettings;
            drawnow;
            if(isfield(Settings.Default, "psDataFolder") == 1)
                psDataFolder = Settings.Default.psDataFolder;
                % Check whether psDataFolder is a folder.
                if(exist(char(Settings.Default.psDataFolder), "dir") ~= 7)
                    clear psDataFolder;
                end
            end
            % If psDataFolder is unspecified check whether
            % PSTrace method folder exists in user data.
            if(exist("psDataFolder", "var") == 0)
                % Get user folder.
                userFolder = getenv("USERPROFILE");
                if(exist(userFolder + "\My Documents\PSData", "dir") == 7)
                    psDataFolder = userFolder + "\My Documents\PSData\";
                end
            end
            % Show load method dialog.
            drawnow;
            pause(0.05);
            if(exist("psDataFolder", "var") == 0)
                [methodName, methodFolder] =...
                    uigetfile("*.psmethod", "Please specify the " + ...
                    "location of a *.psmethod file");
            else
                [methodName, methodFolder] =...
                    uigetfile("*.psmethod", "Please specify the " + ...
                    "location of a *.psmethod file", psDataFolder);
            end
            drawnow;
            pause(0.05);
            figure(app.DataacquisitionUIFigure);
            % Store folder location of method for future reference.
            Settings.Default.psDataFolder = methodFolder;
            SettingsLocal = Settings;
            Settings = cell(0);
            Settings.Default = SettingsLocal.Default;
            save('settings.mat','Settings','-append');
            log(app, methodFolder + " set as the default data folder " + ...
                "in settings.mat.");
            % Full method path required for the LoadMethod function.
            methodPath = fullfile(methodFolder, methodName);
            % Load the method object from the specified path.
            method = LoadMethod(methodPath);
            % Check whether the method was succesfully loaded.
            if(method == false)
                log(app, "Error loading method, please check if " + ...
                    "the PalmSens SDK has been loaded, the specified " + ...
                    "file path is correct and the *.psmethod file is valid.");
                return;
            end
            log(app, "Succesfully loaded the [ " + char(method.Name)...
                + " ] method " +  methodName + ".");
            % Update the figure setting fields with the setting
            % from the loaded method.
            technique = getMethodTechnique(app, method);
            app.TechniqueDropDown.Value = technique;
            updateMethodFields(app, method, technique);
            % Store method and update figure.
            app.curSettings.Method.method = method;
        end

        % Button pushed function: SaveMethodButton
        function SaveMethodButtonPushed(app, event)
            [valid, method] = updateMethod(app);
            if(valid == false)
                log(app, "Error! Could not save the method. " + ...
                    "Please make sure the methods settings are correct.");
                return;
            end
            % Store the method in the figures memory.
            app.curSettings.Method.method = method;
            % Check whether settings contain default data folder.
            Settings = app.curSettings;
            if(isfield(Settings.Default, "psDataFolder") == 1)
                psDataFolder = Settings.Default.psDataFolder;
                % Check whether psDataFolder is a folder.
                if(exist(char(Settings.Default.psDataFolder), "dir") ~= 7)
                    clear psDataFolder;
                end
            end
            % If psDataFolder is unspecified check whether
            % PSTrace method folder exists in user data.
            if(exist('psDataFolder', 'var') == 0)
                % Get user folder.
                userFolder = getenv('USERPROFILE');
                if(exist([userFolder '\My Documents\PSData'], 'dir') == 7)
                    psDataFolder = [userFolder '\My Documents\PSData\'];
                end
            end
            % Show load method dialog.
            if(exist('psDataFolder', 'var') == 0)
                [methodName,methodFolder] =...
                    uiputfile('*.psmethod',['Please specify where ' ...
                    'you would like to save the method']);
            else
                [methodName,methodFolder] =...
                    uiputfile('*.psmethod',['Please specify where ' ...
                    'you would like to save the method'], psDataFolder);
            end
            % Saving the method.
            log(app, "Saving the method...");
            fullPath = fullfile(methodFolder, methodName);
            saved = SaveMethod(method, fullPath, app.curSettings.PSSDK);
            if(saved == true)
                log(app, "Successfully saved the method to " + fullPath);
            else
                log(app, "An error occured while saving the method.");
            end
        end

        % Button pushed function: RunInstanceButton
        function RunInstanceButtonPushed(app, event)
            % Run or abort measurement.
            if(strcmp(app.RunInstanceButton.Text, "Run Instance"))
                startNewMeasuring(app);
                app.RunInstanceButton.Text = "Abort";
                app.RunInstanceButton.FontColor = "white";
                app.RunInstanceButton.BackgroundColor = "red";
                app.RunScheduleButton.Enable = "off";
            else
                abortMeasuring(app);
                app.RunInstanceButton.Text = 'Run Instance';
                app.RunInstanceButton.FontColor = "black";
                app.RunInstanceButton.BackgroundColor = [0.96, 0.96, 0.96];
                app.RunScheduleButton.Enable = "on";
            end
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            Settings = app.curSettings;
            if(isfield(Settings.Default, "psDataFolder") == 1)
                psDataFolder = Settings.Default.psDataFolder;
                % Check whether psDataFolder is a folder.
                if(exist(char(Settings.Default.psDataFolder), "dir") ~= 7)
                    clear psDataFolder;
                end
            end
            % If psDataFolder is unspecified check whether PSTrace method
            % folder exists in user data.
            if(exist('psDataFolder','var') == 0)
                % Get user folder.
                userFolder = getenv("userprofile");
                if(exist([userFolder '\My Documents\PSData'], 'dir') == 7)
                    psDataFolder = [userFolder '\My Documents\PSData\'];
                end
            end
            % Show load method dialog.
            if(exist("psDataFolder", "var") == 0)
                [name,folder] = uigetfile('*.pssession', 'Please specify the location of a *.pssession file');
            else
                [name,folder] = uigetfile('*.pssession', 'Please specify the location of a *.pssession file', psDataFolder);
            end
            % Store folder location of method for future reference.
            Settings.Default.psDataFolder = folder;
            SettingsLocal = Settings;
            Settings = cell(0);
            Settings.Default = SettingsLocal.Default;
            save('settings.mat', 'Settings', '-append');
            log(app, folder + " set as the default data folder in settings.mat.");
            % Full method path required for the LoadMethod function.
            path = fullfile(folder, name);
            % Load the method object from the specified path.
            session = LoadSession(path);
            % Check whether the session was succesfully loaded.
            if(isstruct(session) == false)
                log(app, ['Error loading session, please check if the ' ...
                    'PalmSens SDK has been loaded, the specified file ' ...
                    'path is correct and the *.pssession file is valid']);
                return;
            end
            log(app, "Succesfully loaded " + num2str(length(session))...
                + " measurement(s) from " + name + ".");
            % Store measurements in figure handle.
            for i = 1:length(session)
                app.Measurement.measurements(end+1) = session(i);
            end
            % Add measurements to overview.
            for i = 1:length(session)
                addToOverview(app, session(i));
            end
            % Update plot.
            updatePlots(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Show save data dialog.
            [name,folder] = uiputfile('*.mat', 'Please specify where you would like to save the measurement');
            if(isempty(name))
                log(app, 'Saving cancelled, no path or file name specified.');
            end
            fullPath = fullfile(folder,name);
            % Get the selected measurement.
            measurement = app.Measurement.measurements(app.Measurement1DropDown.Value);
            save(fullPath, 'measurement');
            log(app, ['Successfully saved the measurement to ' fullPath]);
        end

        % Value changed function: AxisequalButton
        function AxisequalButtonValueChanged(app, event)
            updatePlots(app);
        end

        % Value changed function: Measurement1DropDown
        function Measurement1DropDownValueChanged(app, event)
            updatePlots(app);
            if(app.Measurement1DropDown.Value > 1)
                app.SaveButton.Enable = "on";
            else
                app.SaveButton.Enable = "off";
            end
        end

        % Value changed function: Measurement2DropDown
        function Measurement2DropDownValueChanged(app, event)
            updatePlots(app);
        end

        % Value changed function: RepeatCheckBox
        function RepeatCheckBoxValueChanged(app, event)
            value = app.RepeatCheckBox.Value;
            app.CycleEditField.Enable = value;
            app.PeriodEditField.Enable = value;
            isEnabled = strcmp(app.ConnectButton.Text, "Disconnect");
            app.RunScheduleButton.Enable = value && isEnabled;
        end

        % Button pushed function: RunScheduleButton
        function RunScheduleButtonPushed(app, event)
            % Run or abort measurement.
            if(strcmp(app.RunScheduleButton.Text, "Run Schedule"))
                cycle = app.CycleEditField.Value;
                period = app.PeriodEditField.Value;
                app.aTimer.TasksToExecute = cycle;
                app.aTimer.Period = period * 60;
                app.aTimer.TimerFcn = @startNewMeasuring;
                app.RunScheduleButton.Text = "Abort";
                app.RunScheduleButton.FontColor = "white";
                app.RunScheduleButton.BackgroundColor = "red";
                app.RunInstanceButton.Enable = "off";
            else
                stop(app.aTimer);
                abortMeasuring(app);
                app.RunScheduleButton.Text = 'Run Schedule';
                app.RunScheduleButton.FontColor = "black";
                app.RunScheduleButton.BackgroundColor = [0.96, 0.96, 0.96];
                app.RunInstanceButton.Enable = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataacquisitionUIFigure = uifigure('Visible', 'off');
            app.DataacquisitionUIFigure.Name = 'Data acquisition';
            app.GridLayout = uigridlayout(app.DataacquisitionUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ConnecttodevicesPanel = uipanel(app.GridLayout2);
            app.ConnecttodevicesPanel.Title = 'Connect to devices';
            app.GridLayout3 = uigridlayout(app.ConnecttodevicesPanel);
            app.DevicesDropDown = uidropdown(app.GridLayout3);
            app.DevicesDropDown.Items = {};
            app.DevicesDropDown.Placeholder = 'Device name';
            app.DevicesDropDown.Value = {};
            app.ConnectButton = uibutton(app.GridLayout3, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.RefreshButton = uibutton(app.GridLayout3, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.MeasuretechniquesettingsPanel = uipanel(app.GridLayout5);
            app.MeasuretechniquesettingsPanel.Title = 'Measure technique settings';
            app.GridLayout7 = uigridlayout(app.MeasuretechniquesettingsPanel);
            app.CurrentrangePanel = uipanel(app.GridLayout7);
            app.CurrentrangePanel.Title = 'Current range';
            app.GridLayout4_2 = uigridlayout(app.CurrentrangePanel);
            app.MinDropDownLabel = uilabel(app.GridLayout4_2);
            app.MinDropDownLabel.Text = 'Min';
            app.MinDropDown = uidropdown(app.GridLayout4_2);
            app.MinDropDown.Items = {'10mA', '1mA', '100uA', '10uA', '1uA', '100nA', '10nA', '1nA'};
            app.MinDropDown.Value = '10mA';
            app.MaxDropDownLabel = uilabel(app.GridLayout4_2);
            app.MaxDropDownLabel.Text = 'Max';
            app.MaxDropDown = uidropdown(app.GridLayout4_2);
            app.MaxDropDown.Items = {'10mA', '1mA', '100uA', '10uA', '1uA', '100nA', '10nA', '1nA'};
            app.MaxDropDown.Value = '10mA';
            app.StartDropDownLabel = uilabel(app.GridLayout4_2);
            app.StartDropDownLabel.Text = 'Start';
            app.StartDropDown = uidropdown(app.GridLayout4_2);
            app.StartDropDown.Items = {'10mA', '1mA', '100uA', '10uA', '1uA', '100nA', '10nA', '1nA'};
            app.StartDropDown.Value = '10mA';
            app.PretreatmentsettingsPanel = uipanel(app.GridLayout7);
            app.PretreatmentsettingsPanel.Title = 'Pretreatment settings';
            app.GridLayout7_2 = uigridlayout(app.PretreatmentsettingsPanel);
            app.EconditionEditFieldLabel = uilabel(app.GridLayout7_2);
            app.EconditionEditFieldLabel.Text = 'E condition';
            app.EconditionEditField = uieditfield(app.GridLayout7_2, 'numeric');
            app.VLabel = uilabel(app.GridLayout7_2);
            app.VLabel.Text = 'V';
            app.tConditionEditFieldLabel = uilabel(app.GridLayout7_2);
            app.tConditionEditFieldLabel.Text = 't condition';
            app.tConditionEditField = uieditfield(app.GridLayout7_2, 'numeric');
            app.sLabel = uilabel(app.GridLayout7_2);
            app.sLabel.Text = 's';
            app.EdepositionEditFieldLabel = uilabel(app.GridLayout7_2);
            app.EdepositionEditFieldLabel.Text = 'E deposition';
            app.EdepositionEditField = uieditfield(app.GridLayout7_2, 'numeric');
            app.VLabel_2 = uilabel(app.GridLayout7_2);
            app.VLabel_2.Text = 'V';
            app.tDepositionEditFieldLabel = uilabel(app.GridLayout7_2);
            app.tDepositionEditFieldLabel.Text = 't deposition';
            app.tDepositionEditField = uieditfield(app.GridLayout7_2, 'numeric');
            app.sLabel_2 = uilabel(app.GridLayout7_2);
            app.sLabel_2.Text = 's';
            app.tEquilibrationEditField = uieditfield(app.GridLayout7_2, 'numeric');
            app.tEquilibrationEditFieldLabel = uilabel(app.GridLayout7_2);
            app.tEquilibrationEditFieldLabel.Text = 't equilibration';
            app.sLabel_3 = uilabel(app.GridLayout7_2);
            app.sLabel_3.Text = 's';
            app.OtherSettingsPanel = uipanel(app.GridLayout7);
            app.OtherSettingsPanel.Title = 'Settings';
            app.GridLayout8 = uigridlayout(app.OtherSettingsPanel);
            app.Label = uilabel(app.GridLayout8);
            app.Label.Text = {'This example does not include the controls to view/edit this measurement technique. '; ''; 'Follow the examples of the LSV, CV and EIS panels to create your own panel for this type of measurement technique.'};
            app.CVSettingsPanel = uipanel(app.GridLayout7);
            app.CVSettingsPanel.Title = 'Cyclic voltammetry settings';
            app.GridLayout11 = uigridlayout(app.CVSettingsPanel);
            app.EbeginEditField_2Label = uilabel(app.GridLayout11);
            app.EbeginEditField_2Label.Text = 'E begin';
            app.CVEbeginEditField = uieditfield(app.GridLayout11, 'numeric');
            app.VLabel_9 = uilabel(app.GridLayout11);
            app.VLabel_9.Text = 'V';
            app.Evertex1EditFieldLabel = uilabel(app.GridLayout11);
            app.Evertex1EditFieldLabel.Text = 'E vertex 1';
            app.CVEvertex1EditField = uieditfield(app.GridLayout11, 'numeric');
            app.VLabel_10 = uilabel(app.GridLayout11);
            app.VLabel_10.Text = 'V';
            app.Evertex2EditFieldLabel = uilabel(app.GridLayout11);
            app.Evertex2EditFieldLabel.Text = 'E vertex 2';
            app.CVEvertex2EditField = uieditfield(app.GridLayout11, 'numeric');
            app.VLabel_11 = uilabel(app.GridLayout11);
            app.VLabel_11.Text = 'V';
            app.EstepEditField_2Label = uilabel(app.GridLayout11);
            app.EstepEditField_2Label.Text = 'E step';
            app.CVEstepEditField = uieditfield(app.GridLayout11, 'numeric');
            app.VLabel_12 = uilabel(app.GridLayout11);
            app.VLabel_12.Text = 'V';
            app.ScanrateEditField_2Label = uilabel(app.GridLayout11);
            app.ScanrateEditField_2Label.Text = 'Scan rate';
            app.CVScanRateEditField = uieditfield(app.GridLayout11, 'numeric');
            app.VsLabel_2 = uilabel(app.GridLayout11);
            app.VsLabel_2.Text = 'V/s';
            app.NumberofscansEditFieldLabel = uilabel(app.GridLayout11);
            app.NumberofscansEditFieldLabel.Text = 'Number of scans';
            app.CVNumberOfScansEditField = uieditfield(app.GridLayout11, 'numeric');
            app.CASettingsPanel = uipanel(app.GridLayout7);
            app.CASettingsPanel.Title = 'Chronoamperometry settings';
            app.GridLayout10 = uigridlayout(app.CASettingsPanel);
            app.EDCEditFieldLabel_2 = uilabel(app.GridLayout10);
            app.EDCEditFieldLabel_2.Text = 'E DC';
            app.CAEDCEditField = uieditfield(app.GridLayout10, 'numeric');
            app.VLabel_8 = uilabel(app.GridLayout10);
            app.VLabel_8.Text = 'V';
            app.tintervalEditFieldLabel = uilabel(app.GridLayout10);
            app.tintervalEditFieldLabel.Text = 't interval';
            app.CAtIntervalEditField = uieditfield(app.GridLayout10, 'numeric');
            app.sLabel_4 = uilabel(app.GridLayout10);
            app.sLabel_4.Text = 's';
            app.trunEditFieldLabel = uilabel(app.GridLayout10);
            app.trunEditFieldLabel.Text = 't run';
            app.CAtRunEditField = uieditfield(app.GridLayout10, 'numeric');
            app.sLabel_5 = uilabel(app.GridLayout10);
            app.sLabel_5.Text = 's';
            app.LSVSettingsPanel = uipanel(app.GridLayout7);
            app.LSVSettingsPanel.Title = 'Linear Sweep Voltammetry Settings';
            app.GridLayout9 = uigridlayout(app.LSVSettingsPanel);
            app.EbeginEditFieldLabel = uilabel(app.GridLayout9);
            app.EbeginEditFieldLabel.Text = 'E begin';
            app.LSVEbeginEditField = uieditfield(app.GridLayout9, 'numeric');
            app.VLabel_5 = uilabel(app.GridLayout9);
            app.VLabel_5.Text = 'V';
            app.EendEditFieldLabel = uilabel(app.GridLayout9);
            app.EendEditFieldLabel.Text = 'E end';
            app.LSVEendEditField = uieditfield(app.GridLayout9, 'numeric');
            app.VLabel_6 = uilabel(app.GridLayout9);
            app.VLabel_6.Text = 'V';
            app.ScanrateEditFieldLabel = uilabel(app.GridLayout9);
            app.ScanrateEditFieldLabel.Text = 'Scan rate';
            app.LSVScanRateEditField = uieditfield(app.GridLayout9, 'numeric');
            app.VsLabel = uilabel(app.GridLayout9);
            app.VsLabel.Text = 'V/s';
            app.EstepEditFieldLabel = uilabel(app.GridLayout9);
            app.EstepEditFieldLabel.Text = 'E step';
            app.LSVEstepEditField = uieditfield(app.GridLayout9, 'numeric');
            app.VLabel_7 = uilabel(app.GridLayout9);
            app.VLabel_7.Text = 'V';
            app.EISSettingsPanel = uipanel(app.GridLayout7);
            app.EISSettingsPanel.Title = 'Impedance spectroscopy settings';
            app.GridLayout12 = uigridlayout(app.EISSettingsPanel);
            app.FixedpotentialLabel = uilabel(app.GridLayout12);
            app.FixedpotentialLabel.Text = 'Fixed potential:';
            app.EDCEditFieldLabel = uilabel(app.GridLayout12);
            app.EDCEditFieldLabel.Text = 'E DC';
            app.EISEDCEditField = uieditfield(app.GridLayout12, 'numeric');
            app.VLabel_3 = uilabel(app.GridLayout12);
            app.VLabel_3.Text = 'V';
            app.EACEditFieldLabel = uilabel(app.GridLayout12);
            app.EACEditFieldLabel.Text = 'E AC';
            app.EISEACEditField = uieditfield(app.GridLayout12, 'numeric');
            app.VLabel_4 = uilabel(app.GridLayout12);
            app.VLabel_4.Text = 'V';
            app.FrequencyscanLabel = uilabel(app.GridLayout12);
            app.FrequencyscanLabel.Text = 'Frequency scan:';
            app.nfrequenciesEditFieldLabel = uilabel(app.GridLayout12);
            app.nfrequenciesEditFieldLabel.Text = 'n frequencies';
            app.EISnFrequenciesEditField = uieditfield(app.GridLayout12, 'numeric');
            app.MinfrequencyEditFieldLabel = uilabel(app.GridLayout12);
            app.MinfrequencyEditFieldLabel.Text = 'Min frequency';
            app.EISMinFrequencyEditField = uieditfield(app.GridLayout12, 'numeric');
            app.HzLabel = uilabel(app.GridLayout12);
            app.HzLabel.Text = 'Hz';
            app.MaxfrequencyEditFieldLabel = uilabel(app.GridLayout12);
            app.MaxfrequencyEditFieldLabel.Text = 'Max frequency';
            app.EISMaxFrequencyEditField = uieditfield(app.GridLayout12, 'numeric');
            app.HzLabel_2 = uilabel(app.GridLayout12);
            app.HzLabel_2.Text = 'Hz';
            app.LogPanel = uipanel(app.GridLayout5);
            app.LogPanel.Title = 'Log';
            app.GridLayout6 = uigridlayout(app.LogPanel);
            app.LogBox = uilistbox(app.GridLayout6);
            app.GridLayout13 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout13);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout14 = uigridlayout(app.GridLayout13);
            app.Measurement1DropDownLabel = uilabel(app.GridLayout14);
            app.Measurement1DropDownLabel.Text = 'Measurement 1';
            app.Measurement1DropDown = uidropdown(app.GridLayout14);
            app.Measurement1DropDown.ValueChangedFcn = createCallbackFcn(app, @Measurement1DropDownValueChanged, true);
            app.Measurement2DropDownLabel = uilabel(app.GridLayout14);
            app.Measurement2DropDownLabel.Text = 'Measurement 2';
            app.Measurement2DropDown = uidropdown(app.GridLayout14);
            app.Measurement2DropDown.ValueChangedFcn = createCallbackFcn(app, @Measurement2DropDownValueChanged, true);
            app.ImportButton = uibutton(app.GridLayout14, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.SaveButton = uibutton(app.GridLayout14, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.AxisequalButton = uibutton(app.GridLayout14, 'state');
            app.AxisequalButton.ValueChangedFcn = createCallbackFcn(app, @AxisequalButtonValueChanged, true);
            app.AxisequalButton.Text = 'Axis equal';
            app.MethodPanel = uipanel(app.GridLayout);
            app.MethodPanel.Title = 'Method';
            app.GridLayout4 = uigridlayout(app.MethodPanel);
            app.LoadMethodButton = uibutton(app.GridLayout4, 'push');
            app.LoadMethodButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMethodButtonPushed, true);
            app.LoadMethodButton.Text = 'Load';
            app.SaveMethodButton = uibutton(app.GridLayout4, 'push');
            app.SaveMethodButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMethodButtonPushed, true);
            app.SaveMethodButton.Text = 'Save';
            app.TechniqueDropDown = uidropdown(app.GridLayout4);
            app.TechniqueDropDown.Items = {'Linear Sweep Voltammetry', 'Cyclic Voltammetry', 'AC Voltammetry    ', 'Differential Pulse Voltammetry    ', 'Square Wave Voltammetry', 'Normal Pulse Voltammetry', 'Chronoamperometry', 'MultiStep Amperometry', 'Fast Amperometry', 'Pulsed Amperometric Detection', 'Multiple Pulse Amperometry', 'Open Circuit Potentiometry', 'Chronopotentiometry', 'MultiStep Potentiometry', 'Chronopotentiometric Stripping', 'Impedance Spectroscopy (PS3 and PS4 only)'};
            app.TechniqueDropDown.ValueChangedFcn = createCallbackFcn(app, @TechniqueDropDownValueChanged, true);
            app.TechniqueDropDown.Value = 'Chronoamperometry';
            app.RunInstanceButton = uibutton(app.GridLayout4, 'push');
            app.RunInstanceButton.ButtonPushedFcn = createCallbackFcn(app, @RunInstanceButtonPushed, true);
            app.RunInstanceButton.Text = 'Run Instance';
            app.GridLayout15 = uigridlayout(app.GridLayout4);
            app.RepeatCheckBox = uicheckbox(app.GridLayout15);
            app.RepeatCheckBox.ValueChangedFcn = createCallbackFcn(app, @RepeatCheckBoxValueChanged, true);
            app.RepeatCheckBox.Text = 'Repeat';
            app.RepeatCheckBox.Value = true;
            app.RunScheduleButton = uibutton(app.GridLayout15, 'push');
            app.RunScheduleButton.ButtonPushedFcn = createCallbackFcn(app, @RunScheduleButtonPushed, true);
            app.RunScheduleButton.Text = 'Run Schedule';
            app.CycleEditFieldLabel = uilabel(app.GridLayout15);
            app.CycleEditFieldLabel.Text = 'Cycle';
            app.CycleEditField = uieditfield(app.GridLayout15, 'numeric');
            app.CycleEditField.Limits = [1 Inf];
            app.CycleEditField.RoundFractionalValues = 'on';
            app.CycleEditField.ValueDisplayFormat = '%.0f';
            app.CycleEditField.Value = 1;
            app.PeriodEditFieldLabel = uilabel(app.GridLayout15);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.PeriodEditField = uieditfield(app.GridLayout15, 'numeric');
            app.PeriodEditField.Limits = [0 Inf];
            app.PeriodEditField.Value = 20;
            app.timesLabel = uilabel(app.GridLayout15);
            app.timesLabel.Text = 'time(s)';
            app.minLabel = uilabel(app.GridLayout15);
            app.minLabel.Text = 'min';
            % Show the figure after all components are created
            app.DataacquisitionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_acquisition

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DataacquisitionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.DataacquisitionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataacquisitionUIFigure)
        end
    end
end